% description: Spectroscopy Data Analysis
% (c) 2017 Petar Lambrev
classdef spectromatic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrOMaticUIFigure            matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadmatfileMenu                 matlab.ui.container.Menu
        SavematfileMenu                 matlab.ui.container.Menu
        ImportdataMenu                  matlab.ui.container.Menu
        ExportdataMenu                  matlab.ui.container.Menu
        CopytoclipboardMenu             matlab.ui.container.Menu
        WorkspaceMenu                   matlab.ui.container.Menu
        TransferalltoMATLABMenu         matlab.ui.container.Menu
        TransferselectedMenu            matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataBrowserTab                  matlab.ui.container.Tab
        FileIOPanel                     matlab.ui.container.Panel
        FileImportButton                matlab.ui.control.Button
        FileExportButton                matlab.ui.control.Button
        FileLoadButton                  matlab.ui.control.Button
        FileSaveButton                  matlab.ui.control.Button
        CopySpectraButton               matlab.ui.control.Button
        MATLABWorkspacePanel            matlab.ui.container.Panel
        WSExportSelectionButton         matlab.ui.control.Button
        WSExportButton                  matlab.ui.control.Button
        BrowserTabGroup                 matlab.ui.container.TabGroup
        FigureTab                       matlab.ui.container.Tab
        BrowserFigure                   matlab.ui.control.UIAxes
        TableTab                        matlab.ui.container.Tab
        BrowserTable                    matlab.ui.control.Table
        HistoryTab                      matlab.ui.container.Tab
        OperationshistoryTextAreaLabel  matlab.ui.control.Label
        HistoryTextBox                  matlab.ui.control.TextArea
        ObjectsPanel                    matlab.ui.container.Panel
        DatasetRenameObjectButton       matlab.ui.control.Button
        DatasetDeleteObjectButton       matlab.ui.control.Button
        DataSetCopyObjectButton         matlab.ui.control.Button
        DatasetObjectsListBox           matlab.ui.control.ListBox
        GroupsPanel                     matlab.ui.container.Panel
        AutoButton                      matlab.ui.control.Button
        DeleteGroupButton               matlab.ui.control.Button
        AutoIndexDropDown               matlab.ui.control.DropDown
        DatasetGroupsListBox            matlab.ui.control.ListBox
        SpectraPanel                    matlab.ui.container.Panel
        PlotDataListBox                 matlab.ui.control.ListBox
        FigurecontrolPanel              matlab.ui.container.Panel
        HoldButton                      matlab.ui.control.StateButton
        LegendButton                    matlab.ui.control.StateButton
        PeaksButton                     matlab.ui.control.Button
        FigSaveButton                   matlab.ui.control.Button
        FigCopyButton                   matlab.ui.control.Button
        DataTab                         matlab.ui.container.Tab
        SELPanel                        matlab.ui.container.Panel
        DataTable                       matlab.ui.control.Table
        PickObjectPanel                 matlab.ui.container.Panel
        ObjectDropDownLabel             matlab.ui.control.Label
        SelectDataDropDown              matlab.ui.control.DropDown
        GroupDropDownLabel              matlab.ui.control.Label
        SelectGroupDropDown             matlab.ui.control.DropDown
        SelectPanel                     matlab.ui.container.Panel
        SeletAllButton                  matlab.ui.control.Button
        NoneButton                      matlab.ui.control.Button
        FinderButtonGroup               matlab.ui.container.ButtonGroup
        FindAddButton                   matlab.ui.control.Button
        FindField                       matlab.ui.control.DropDown
        FindSubtractButton              matlab.ui.control.Button
        ContainsDropDown                matlab.ui.control.DropDown
        FindMultiplyButton              matlab.ui.control.Button
        CALPanel                        matlab.ui.container.Panel
        SaveSelectionPanel              matlab.ui.container.Panel
        NameEditFieldLabel              matlab.ui.control.Label
        SelectSaveNameEditField         matlab.ui.control.EditField
        SelectSaveGroupButton           matlab.ui.control.Button
        SelectSaveObjectButton          matlab.ui.control.Button
        SelectEnterButton               matlab.ui.control.Button
        SelectDataSwitch                matlab.ui.control.Switch
        EnterScalarPanel                matlab.ui.container.Panel
        ValueSpinnerLabel               matlab.ui.control.Label
        MathScalarSpinner               matlab.ui.control.Spinner
        ScalarEnterButton               matlab.ui.control.Button
        OperationPanel                  matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        CEButton                        matlab.ui.control.Button
        ExecuteButton                   matlab.ui.control.Button
        GRPButton                       matlab.ui.control.StateButton
        CommandField                    matlab.ui.control.EditField
        MathLamp                        matlab.ui.control.Lamp
        FunctionsPanel                  matlab.ui.container.Panel
        RangeSpinnerLabel               matlab.ui.control.Label
        MathRange1                      matlab.ui.control.Spinner
        areaButton                      matlab.ui.control.Button
        minButton                       matlab.ui.control.Button
        maxButton                       matlab.ui.control.Button
        XlimButton                      matlab.ui.control.Button
        YXButton                        matlab.ui.control.Button
        mergeButton                     matlab.ui.control.Button
        normButton                      matlab.ui.control.Button
        absButton                       matlab.ui.control.Button
        PlusButton                      matlab.ui.control.Button
        MinusButton                     matlab.ui.control.Button
        MTimesButton                    matlab.ui.control.Button
        MDivideButton                   matlab.ui.control.Button
        meanButton                      matlab.ui.control.Button
        sumButton                       matlab.ui.control.Button
        lnButton                        matlab.ui.control.Button
        NegateButton                    matlab.ui.control.Button
        diffButton                      matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        log10Button                     matlab.ui.control.Button
        smoothButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        MathRange2                      matlab.ui.control.Spinner
        CALMemoryLabel                  matlab.ui.control.Label
        CALInfoBox                      matlab.ui.control.EditField
        SELEditFieldLabel               matlab.ui.control.Label
        SELInfoBox                      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Data = struct;     % Data variables (struct of specdata)
        Groups = struct;   % Named groups per data variable (struct of logical)
        History = struct;  % History of operations
        RegA               % Calculator data register
        RegB               % Calculator data register
        GrpA = struct;     % Calculator grouping register
        GrpB = struct;     % Calculator grouping register
        HstA = {};         % Calculator History register
        HstB = {};         % Calculator History register
        ComText = '';      % Calculator command text
        ComTextB = '';     % Calculator command B text
        OpPending = false; % Calculator pending operation flag
        OpBinary = false;  % Calculator binary operation flag
        OpFunc             % Calculator current function
        OpFuncParam        % Calculator function parameters
        ComTextOld = '';   % Calculator previous command text
        OpBinaryFunc       % Calculator binary function
        PlotData           % Plot data register
        PlotGroups         % Plot data groups
        PlotHistory        % Plot data history
        DataSelection = [] % Selected cells in the data table
        PlotPeakLabels     % Peak label graphics object handles
    end
    
    methods (Access = private)
        
        function refreshDatasetObjects(app, event)
            vars = fieldnames(app.Data);
            app.DatasetObjectsListBox.Items = vars;
            for k = 1:numel(vars)
                if ~isfield(app.History,vars{k})
                    app.History.(vars{k}) = {};
                end
            end
            app.PlotData = []; app.PlotGroups = [];
            app.PlotDataListBox.Value = {};
            cbDatasetObjectsListBox(app, event);
        end
        
        function refreshDataTable(app,varargin)
            var = app.SelectDataDropDown.Value;
            app.DataSelection = [];
            if isempty(var) || ~all(isspecdata(app,var))
                app.DataTable.Data = [];
            else
                grp = app.SelectGroupDropDown.Value;
                if strcmp(grp,'<all data>')
                    data = app.Data.(var);
                else
                    sel = app.Groups.(var).(grp);
                    data = app.Data.(var)(sel);
                end
                data = data(:);
                if isempty(data)
                    app.DataTable.Data = [];
                else
                    if any(strcmp(varargin,'SelectAll'))
                        sel = num2cell(true(numel(data),1));
                    else
                        sel = num2cell(false(numel(data),1));
                    end
                    datacell = [sel,{data.ID}', {data.ExpID}', ...
                        {data.DateTime}', {data.XType}', {data.YType}', {data.XUnit}', {data.YUnit}'];
                    app.DataTable.Data = datacell;
                end
            end
            % app.SelectDataSwitch.Value = 'SEL';
            updateSELInfoBox(app)
        end
        
        function res = FindFind(app)
            % Search for keywords in data
            var = app.SelectDataDropDown.Value;
            gi = groupindex(app, var, app.SelectGroupDropDown.Value);
            field = app.FindField.Value;
            key = app.ContainsDropDown.Value;
            if strcmp(field,'Group')
                if isfield(app.Groups.(var),key)
                    sel = app.Groups.(var).(key);
                    res = sel(gi);
                else
                    res = false(numel(app.Data.(var)(gi)),1);
                end
            else
                data = app.Data.(var)(gi);
                res = data.fi(field,key);
            end
        end
        
        function gi = groupindex(app, var, groupname)
            % GROUPINDEX get logical array for group
            if strcmp(groupname,'<all data>') || ~isfield(app.Groups.(var),groupname)
                gi = true(numel(app.Data.(var)),1);
            else
                gi = app.Groups.(var).(groupname);
            end
            gi = gi(:);
        end
        
        function resetDataComponents(app, event)
            % Refresh Data table and Data selectors
            refreshDatasetObjects(app, event);
            app.SelectDataDropDown.Items = fieldnames(app.Data);
            cbSelectDataDropDown(app, event)
        end
        
        function MathBeginOp(app, func, functext, param)
            % Start Math operation
            if app.OpPending
                MathExecute(app, 0)
            end
            if isempty(app.RegA)
                if strcmp(app.SelectEnterButton.Enable,'on')
                    cbMathEnterObject(app, 0);
                end
            end
            if isempty(app.RegA), return, end
            
            app.OpFunc = func;
            app.OpFuncParam = param;
            app.ComTextOld = app.ComText;
            if isempty(param)
                app.ComText = sprintf(functext, app.ComText);
            else
                app.ComText = sprintf(functext, app.ComText, num2str(param));
            end
            app.OpPending = true;
            updateCALInfoBox(app)
        end
        
        function MathBeginBinaryOp(app, func, functext)
            % Start Binary Math operation
            if app.OpPending
                MathExecute(app, 0)
            end
            if app.OpBinary
                if isempty(app.RegA) || isempty(app.RegB)
                    % Cancel Previous Binary Op
                    cbMathCE(app)
                else
                    % Execute Previous Binary Op
                    MathExecuteBinary(app, 0)
                end
            end
            if isempty(app.RegA)
                if strcmp(app.SelectEnterButton.Enable,'on')
                    cbMathEnterObject(app, 0);
                end
            end
            if isempty(app.RegA), return, end
            
            app.OpBinaryFunc = func;
            app.RegB = app.RegA; app.GrpB = app.GrpA; app.HstB = app.HstA;
            app.RegA = []; app.GrpA = struct; app.HstA = {};
            app.ComTextOld = app.ComText;
            app.ComTextB = sprintf(functext, app.ComText);
            app.ComText = '';
            app.OpBinary = true;
            updateCALInfoBox(app)
        end
        
        function updateCALInfoBox(app)
            app.CommandField.Value = [app.ComTextB, app.ComText];
            Op = app.OpPending | app.OpBinary;
            if isempty(app.RegA)
                app.CALInfoBox.Value = '';
                app.SelectDataSwitch.Value = 'SEL';
                app.SelectDataSwitch.Enable = 'off';
            else
                if isnumeric(app.RegA) && isscalar(app.RegA)
                    app.CALInfoBox.Value = num2str(app.RegA);
                else
                    app.CALInfoBox.Value = sprintf('%s (%d)', class(app.RegA), numel(app.RegA));
                end
                if Op == false
                    app.SelectDataSwitch.Enable = 'on';
                end
            end
            % Orange lamp if calculator holds data
            if isempty(app.RegA) && isempty(app.RegB)
                app.MathLamp.Color = [0.5 0.5 0.5];
            else
                app.MathLamp.Color = [1 0.75 0];
            end
            
        end
        
        function MathExecute(app, TriggeredByUser)
            % Execute Math Operation
            success = false;
            % First execute unary operation
            if app.OpPending
                %                 if isa(app.RegA,'specdata')
                %                     oldIDs = app.RegA.get('ID');
                %                 end
                nBefore = numel(app.RegA);
                if app.GRPButton.Value==1 && ~app.OpBinary
                    % Grouped operation
                    grptab = struct2table(app.GrpA);
                    [grps, gID] = findgroups(grptab);
                    A = specdata.empty(0,1);
                    for k = 1:size(gID,1)
                        try
                            if isempty(app.OpFuncParam)
                                res = app.OpFunc(app.RegA(grps==k));
                            else
                                res = app.OpFunc(app.RegA(app.GrpA.(grps==k)),app.OpFuncParam);
                            end
                            success = true;
                        catch ME
                            success = false;
                            app.ComText = ME.message;
                            break
                        end
                        A = [A; res(:)];
                    end
                    app.RegA = A;
                    if numel(A)==size(gID,1)
                        app.GrpA = table2struct(gID,'ToScalar',true);
                    else
                        app.GrpA = struct;
                    end
                else
                    % Simple operation
                    try
                        if isempty(app.OpFuncParam)
                            app.RegA = app.OpFunc(app.RegA);
                        else
                            app.RegA = app.OpFunc(app.RegA,app.OpFuncParam);
                        end
                        % if ~isa(app.RegA,'specdata') || ~isequal(oldIDs, app.RegA.get('ID'))
                        if numel(app.RegA) ~= nBefore
                            app.GrpA = struct;
                        end
                        success = true;
                    catch ME
                        success = false;
                        app.ComText = ME.message;
                    end
                end
            end
            app.OpPending = false;
            app.OpFunc = []; app.OpFuncParam = [];
            updateCALInfoBox(app)
            if success
                if app.OpBinary==false
                    % Save Result
                    app.Data.CALR = app.RegA;
                    app.Groups.CALR = app.GrpA;
                    app.History.CALR = [app.HstA, app.CommandField.Value];
                    app.SelectDataSwitch.Value = 'CAL';
                    if TriggeredByUser
                        resetDataComponents(app, 0)
                        app.DatasetObjectsListBox.Value = 'CALR';
                        cbDatasetObjectsListBox(app, 0)
                        app.MathLamp.Color = [0 1 0];
                    end
                end
            else
                app.RegA = []; app.GrpA = struct;
            end
        end
        
        function MathExecuteBinary(app, TriggeredByUser)
            success = false;
            if app.OpBinary
                if isempty(app.RegA) || isempty(app.RegB), return; end
                % if isa(app.RegA,'specdata') && isa(app.RegB,'specdata') ...
                if app.GRPButton.Value==1
                    % Grouped operation
                    grptabA = struct2table(app.GrpA);
                    grptabB = struct2table(app.GrpB);
                    [grpsA, AID] = findgroups(grptabA);
                    [grpsB, BID] = findgroups(grptabB);
                    if ~isequal(AID,BID)
                        app.ComText = 'Cannot perform grouped operation. Operands have different groups.';
                        success = false;
                    else
                        B = specdata.empty(0,1);
                        C = false(0,size(BID,2));
                        for k = 1:size(BID,1)
                            try
                                res = app.OpBinaryFunc(app.RegB(grpsB==k), app.RegA(grpsA==k));
                                success = true;
                            catch ME
                                app.ComText = ME.message;
                                success = false; break
                            end
                            B = [B; res(:)];
                            n = numel(res);
                            C = [C; repmat(BID{k,:},n,1)];
                        end
                        app.RegA = B;
                        C = array2table(C);
                        C.Properties.VariableNames = BID.Properties.VariableNames;
                        app.GrpA = table2struct(C,'ToScalar',true);
                    end
                else
                    % Standard operation
                    try
                        app.RegA = app.OpBinaryFunc(app.RegB, app.RegA);
                        success = true;
                    catch ME
                        app.RegA = [];app.ComText = ME.message; app.ComTextB = '';
                        success = false;
                    end
                    % if isa(app.RegA,'specdata') && isa(app.RegB,'specdata') && ...
                    % isequal(app.RegA.get('ID'),app.RegB.get('ID'))
                    if numel(app.RegA)==numel(app.RegB)
                        app.GrpA = app.GrpB;
                    else
                        app.GrpA = struct;
                    end
                end
                app.RegB = [];
                app.OpBinary = false;
                app.OpBinaryFunc = [];
                app.ComText = [app.ComTextB, app.ComText];
                app.ComTextB = '';
            end
            updateCALInfoBox(app)
            if success
                app.SelectDataSwitch.Value = 'CAL';
                % Save Result
                app.Data.CALR = app.RegA;
                app.Groups.CALR = app.GrpA;
                app.HstA = app.HstB; app.HstB = struct;
                app.History.CALR = [app.HstA, app.CommandField.Value];
                if TriggeredByUser
                    resetDataComponents(app, 0)
                    app.DatasetObjectsListBox.Value = 'CALR';
                    cbDatasetObjectsListBox(app, 0)
                    app.MathLamp.Color = [0 1 0];
                end
            end
        end
        
        function s = selectgroup(app)
            var = app.SelectDataDropDown.Value;
            group = app.SelectGroupDropDown.Value;
            sel = cell2mat(app.DataTable.Data(:,1));
            gi = groupindex(app,var,group);
            gix = find(gi);
            selx = gix(sel);
            s = ismember((1:numel(app.Data.(var)))',selx);
        end
        
        function updateSELInfoBox(app)
            var = app.SelectDataDropDown.Value;
            if isempty(var)
                SELtext = 'Nothing selected.';
            elseif isspecdata(app,var)
                sel = cell2mat(app.DataTable.Data(:,1));
                nsel = numel(find(sel));
                ngrp = numel(app.DataTable.Data(:,1));
                SELtext = sprintf('%d selected from %d in group', nsel, ngrp);
            elseif isnumeric(app.Data.(var)) && isscalar(app.Data.(var))
                SELtext = num2str(app.Data.(var));
            else
                SELtext = sprintf('%s (%d)', class(app.Data.(var)), numel(app.Data.(var)));
            end
            app.SELInfoBox.Value = SELtext;
        end
        
        function res = isspecdata(app, vars)
            % Check if data variables are of class specdata
            if ~iscell(vars), vars = {vars}; end
            res = true(1,numel(vars));
            for k = 1:numel(vars)
                res(k) = isa(app.Data.(vars{k}),'specdata');
            end
        end
        
        function updateBrowserFigure(app)
            % Select data
            sel = app.PlotDataListBox.Value;
            if isempty(sel) || isempty(app.PlotData), return, end
            
            dat = app.PlotData(sel);
            
            % Plot data
            LgStr = {};
            if app.HoldButton.Value
                lg = app.BrowserFigure.Legend;
                if ~isempty(lg)
                    LgStr = lg.String;
                end
            else
                cla(app.BrowserFigure);
                app.PlotPeakLabels = [];
            end
            hold(app.BrowserFigure,'on')
            for k = 1:numel(dat)
                nx = numel(dat(k).X);
                if nx > 4 && nx < 32
                    nx = (nx-1) * 10;
                    dx = (max(dat(k).X) - min(dat(k).X)) / nx;
                    try
                        smx = min(dat(k).X):dx:max(dat(k).X);
                        smy = interp1(dat(k).X,dat(k).Y,smx,'spline');
                        plot(app.BrowserFigure,smx,smy,'-o','MarkerIndices',1:10:numel(smx));
                    catch
                        plot(app.BrowserFigure,dat(k).X,dat(k).Y);
                    end
                else
                    plot(app.BrowserFigure, dat(k).X, dat(k).Y)
                end
            end
            
            % Label axes
            if isempty(dat(1).XUnit)
                xlabel(app.BrowserFigure,dat(1).XType);
            else
                xlabel(app.BrowserFigure,sprintf('%s (%s)',dat(1).XType,dat(1).XUnit));
            end
            if isempty(dat(1).YUnit)
                ylabel(app.BrowserFigure,dat(1).YType);
            else
                ylabel(app.BrowserFigure,sprintf('%s (%s)',dat(1).YType,dat(1).YUnit));
            end
            
            legend(app.BrowserFigure,[LgStr, dat.get('ID')],'location','best');
            if app.LegendButton.Value==false
                legend(app.BrowserFigure,'off')
            end
        end
        
        function updateBrowserTable(app)
            if ~isempty(app.PlotData)
                % specdata
                sel = app.PlotDataListBox.Value;
                if isempty(sel)
                    app.BrowserTable.ColumnName = {};
                    app.BrowserTable.Data = [];
                else
                    tbl = app.PlotData(sel).xytable;
                    app.BrowserTable.ColumnName = tbl.Properties.VariableDescriptions;
                    app.BrowserTable.Data = tbl{:,:};
                end
            else
                % numeric data
                var = app.DatasetObjectsListBox.Value;
                if ~isempty(var) && numel(var) == 1 && isnumeric(app.Data.(var{1}))
                    app.BrowserTable.Data = app.Data.(var{1});
                end
            end
        end
        
        function c = cell2char(app,tcell)
            % Unused
            % convert cell array to tab-delimited character array
            for k = 1:size(tcell,1)
                for l = 1:size(tcell,2)-1
                    tcell{k,l} = [tcell{k,l},char(9)];
                end
            end
            ch = cell(size(tcell,1),1);
            for k = 1:length(ch)
                ch{k} = cat(2,tcell{k,:});
            end
            c = char(ch);
        end
        
        function newgroups = getSelectedGroups(app, var, sel)
            newgroups = struct;
            oldgroups = app.Groups.(var);
            groupnames = fieldnames(oldgroups);
            for k = 1:numel(groupnames)
                grp = oldgroups.(groupnames{k});
                grpsel = grp(sel);
                if any(grpsel) && ~all(grpsel)
                    newgroups.(groupnames{k}) = grpsel;
                end
            end
        end
        
        function vartxt = getObjectName(app, var, sel)
            % GETOBJECTNAME Return a string identifying the selection
            
            grp = app.SelectGroupDropDown.Value;
            gi = groupindex(app, var, grp);
            
            if isequal(sel,gi) && ~strcmp(grp,'<all data>')
                % Selection is a group
                vartxt = [var, '(', grp, ')'];
            elseif all(sel)
                % All items selected
                vartxt = var;
            elseif numel(find(sel)) == 1
                % Single selected item
                vartxt = [var,'(<',app.Data.(var)(sel).ID,'>)'];
            else
                % Multiple subselection
                vartxt = sprintf('%s(n=%d)',var,numel(find(sel)));
            end
        end
        
        function refreshBrowser(app)
            if strcmp(app.TabGroup.SelectedTab.Title,'Data Browser')
                switch app.BrowserTabGroup.SelectedTab.Title
                    case 'Figure'
                        updateBrowserFigure(app)
                    case 'Table'
                        updateBrowserTable(app)
                    case 'History'
                        var = app.DatasetObjectsListBox.Value;
                        if ~isempty(var) && ~isempty(app.History.(var{1}))
                            app.HistoryTextBox.Value = app.History.(var{1});
                        else
                            app.HistoryTextBox.Value = {''};
                        end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cbStart(app)
            %             app.OperationPanel.AutoResizeChildren = 'off';
            %             app.FunctionsPanel.AutoResizeChildren = 'off';
            %             app.SaveSelectionPanel.AutoResizeChildren = 'off';
            %             app.EnterScalarPanel.AutoResizeChildren = 'off';
            app.CALPanel.AutoResizeChildren = 'off';
        end

        % Button pushed function: AutoButton
        function cbAutoindex(app, event)
            var = app.DatasetObjectsListBox.Value;
            byField = app.AutoIndexDropDown.Value;
            if isempty(var), return, end
            
            for l = 1:numel(var)
                switch byField
                    case 'ID'
                        idx = app.Data.(var{l}).autoindex;
                        fld = fieldnames(idx);
                        for k = 1:numel(fld)
                            app.Groups.(var{l}).(fld{k}) = idx.(fld{k});
                        end
                    otherwise
                        KeyValues = app.Data.(var{l}).get(byField);
                        KeyValues = unique(KeyValues);
                        if isempty(KeyValues), break, end
                        KeyNames = matlab.lang.makeValidName(KeyValues);
                        for k = 1:numel(KeyNames)
                            findres = app.Data.(var{l}).fi(byField, KeyValues{k});
                            app.Groups.(var{l}).(KeyNames{k}) = findres;
                        end
                end
            end
            resetDataComponents(app, event)
        end

        % Value changed function: ContainsDropDown
        function cbContainsDropDown(app, event)
            sel = cell2mat(app.DataTable.Data(:,1));
            if ~any(sel)
                cbFindAdd(app, event)
            end
            if strcmp(app.SelectDataSwitch.Value,'SEL')
                app.SelectSaveNameEditField.Value = matlab.lang.makeValidName(app.ContainsDropDown.Value);
            end
        end

        % Cell edit callback: DataTable
        function cbDataTableChanged(app, event)
            if strcmp(event.EventName,'CellEdit') && event.Indices(2) > 1
                % Modify properties
                var = app.SelectDataDropDown.Value;
                if isempty(var), return, end
                if ~isa(app.Data.(var),'specdata'), return, end
                FieldName = app.DataTable.ColumnName{event.Indices(2)};
                if isempty(event.NewData), refreshDataTable(app), end
                group = app.SelectGroupDropDown.Value;
                gi = groupindex(app,var,group);
                gix = find(gi);
                selx = gix(event.Indices(1));
                sel = selectgroup(app);
                if any(sel) && any(find(sel)==selx) && ~strcmp(FieldName,'ID')
                    app.Data.(var)(sel) = app.Data.(var)(sel).set(FieldName, event.NewData);
                else
                    app.Data.(var)(selx).(FieldName) = event.NewData;
                end
                % refreshDataTable(app)
                resetDataComponents(app, event)
                app.DatasetObjectsListBox.Value = var;
                cbDatasetObjectsListBox(app, event)
                app.DatasetGroupsListBox.Value = group;
                cbDatasetGroupsListBox(app, event)
            else
                % Change selection of spectra
                updateSELInfoBox(app)
                cbSelectDataSwitch(app, event)
            end
        end

        % Cell selection callback: DataTable
        function cbDataTableSelection(app, event)
            app.DataSelection = event.Indices;
        end

        % Button pushed function: DataSetCopyObjectButton
        function cbDatasetCopyObject(app, event)
            if ~isempty(app.PlotData)
                newvar = inputdlg('New object name','Copy object',1,{'new'});
                if isempty(newvar)
                    return
                else
                    newvar = newvar{1};
                end
                app.Data.(newvar) = app.PlotData;
                if ~isempty(app.PlotGroups)
                    app.Groups.(newvar) = app.PlotGroups;
                end
                app.PlotHistory{end} = [newvar, ' = ', app.PlotHistory{end}];
                app.History.(newvar) = app.PlotHistory;
                % Make groups from the old variable names?
            end
            resetDataComponents(app,event);
        end

        % Button pushed function: DatasetDeleteObjectButton
        function cbDatasetDeleteObject(app, event)
            var = app.DatasetObjectsListBox.Value;
            if ~isempty(var)
                for k = 1:numel(var)
                    app.Data = rmfield(app.Data,var{k});
                    if isfield(app.Groups,var{k})
                        app.Groups = rmfield(app.Groups,var{k});
                    end
                    app.History = rmfield(app.History,var{k});
                end
            end
            resetDataComponents(app, event)
            cbPlotDataListBox(app, event)
        end

        % Value changed function: DatasetGroupsListBox
        function cbDatasetGroupsListBox(app, event)
            if ~isempty(app.PlotData)
                grp = app.DatasetGroupsListBox.Value;
                if strcmp(grp{1},'<all data>')
                    gi = true(numel(app.PlotData),1);
                else
                    gi = false(numel(app.PlotData),1);
                    for k = 1:numel(grp)
                        gi = gi | app.PlotGroups.(grp{k});
                    end
                end
                specid = get(app.PlotData(gi),'ID');
                app.PlotDataListBox.Items = specid;
                app.PlotDataListBox.ItemsData = find(gi);
            else
                app.PlotDataListBox.Items = {};
            end
            if app.HoldButton.Value == 1
                app.PlotDataListBox.Value = {};
            else
                cbPlotDataListBox(app, event)
            end
            
        end

        % Value changed function: DatasetObjectsListBox
        function cbDatasetObjectsListBox(app, event)
            alldata = '<all data>';
            if numel(app.DatasetObjectsListBox.Items) == 1
                app.DatasetObjectsListBox.Value = app.DatasetObjectsListBox.Items(1);
            end
            var = app.DatasetObjectsListBox.Value;
            if numel(var) > 1
                % If multiple selection then only specdata objects count
                var = var(isspecdata(app,var));
            end
            
            if isempty(var)
                % Nothing selected
                app.DatasetGroupsListBox.Items = {};
            elseif isnumeric(app.Data.(var{1}))
                app.DatasetGroupsListBox.Items = {alldata};
                app.PlotData = []; app.PlotGroups = struct; app.PlotHistory = struct;
                app.BrowserTabGroup.SelectedTab = app.TableTab;
            else
                % Copy selected variables to Plot Register
                app.PlotData = specdata.empty(0,0);
                app.PlotGroups = struct;
                % Loop over variables and copy
                for k = 1:numel(var)
                    data = app.Data.(var{k});
                    app.PlotData = [app.PlotData; data(:)];
                end
                % Make a combined list of groups
                groups = {};
                for k = 1:numel(var)
                    if isfield(app.Groups,var{k})
                        groups = [groups; fieldnames(app.Groups.(var{k}))];
                    end
                end
                groups = unique(groups);
                % Copy groups
                app.PlotGroups = struct;
                if ~isempty(groups)
                    for k = 1:numel(groups)
                        idx = false(0,1);
                        for l = 1:numel(var)
                            if isfield(app.Groups,var{l}) && isfield(app.Groups.(var{l}),groups{k})
                                g = app.Groups.(var{l}).(groups{k});
                                g = g(:);
                            else
                                g = false(numel(app.Data.(var{l})),1);
                            end
                            idx = [idx; g];
                        end
                        app.PlotGroups.(groups{k}) = idx;
                    end
                end
                if ~isempty(fieldnames(app.PlotGroups))
                    app.DatasetGroupsListBox.Items = [alldata; fieldnames(app.PlotGroups)];
                else
                    app.DatasetGroupsListBox.Items = {alldata};
                end
                app.DatasetGroupsListBox.Value = alldata;
                % Copy history
                app.PlotHistory = app.History.(var{1});
                if numel(var) > 1
                    AddString = '';
                    for k = 2:numel(var)
                        AddString = [', ', var{k}];
                    end
                    app.PlotHistory{end+1} = ['[', var{1}, AddString, ']'];
                else
                    app.PlotHistory{end+1} = var{1};
                end
            end
            cbDatasetGroupsListBox(app, event);
        end

        % Button pushed function: DatasetRenameObjectButton
        function cbDatasetRenameObject(app, event)
            if ~isempty(app.PlotData)
                newvar = inputdlg('New object name','Rename object',1,{'new'});
                if isempty(newvar), return, else
                    newvar = newvar{1};
                end
                app.Data.(newvar) = app.PlotData;
                if ~isempty(app.PlotGroups)
                    app.Groups.(newvar) = app.PlotGroups;
                end
                if ~isempty(app.PlotHistory)
                    app.PlotHistory{end} = [newvar, ' = ', app.PlotHistory{end}];
                    app.History.(newvar) = app.PlotHistory;
                end
                cbDatasetDeleteObject(app, event);
            end
        end

        % Button pushed function: DeleteGroupButton
        function cbDeleteGroup(app, event)
            var = app.DatasetObjectsListBox.Value;
            grp = app.DatasetGroupsListBox.Value;
            if isempty(var) || isempty(grp) || strcmp(grp{1},'<all data>'), return, end;
            for k = 1:numel(var)
                for l = 1:numel(grp)
                    if isfield(app.Groups.(var{k}),grp{l})
                        app.Groups.(var{k}) = rmfield(app.Groups.(var{k}),grp{l});
                    end
                end
            end
            resetDataComponents(app, event)
        end

        % Callback function: ExportdataMenu, FileExportButton
        function cbFileExport(app, event)
            FileFilter = {'*.mat', 'MATLAB File'
                '*.txt', 'Tab-Delimited Text File'
                '*.csv', 'Comma-Delimited Values'
                '*.xlsx', 'Microsoft Excel Spreadsheet'
                '*.xls', 'Excel 1997-2003 Spreadsheet'};
            [FileName,FilePath,FilterIndex] = uiputfile(FileFilter,'Select file');
            drawnow;
            app.SpectrOMaticUIFigure.Visible = 'off';
            app.SpectrOMaticUIFigure.Visible = 'on';
            
            if ~isequal(FileName,0) && ~isequal(FilePath,0)
                FullFile = [FilePath,FileName];
                if FilterIndex == 1 % Save MATLAB
                    vars = app.DatasetObjectsListBox.Value;
                    if ~isempty(vars)
                        flds = setdiff(fieldnames(app.Data),vars);
                        SaveData = rmfield(app.Data,flds);
                        save(FullFile,'-struct','SaveData')
                    end
                else
                    sel = app.PlotDataListBox.Value;
                    if ~isempty(app.PlotData) && ~isempty(sel)
                        dat = app.PlotData(sel);
                        if FilterIndex == 2 % Save TXT
                            dat.save(FullFile);
                        else % Save Table
                            tbl = dat.xytable;
                            writetable(tbl,FullFile);
                        end
                    else
                        vars = app.DatasetObjectsListBox.Value;
                        if ~isempty(vars)
                            flds = setdiff(fieldnames(app.Data),vars);
                            SaveData = rmfield(app.Data,flds);
                            SaveTable = struct2table(SaveData);
                            writetable(SaveTable,FullFile);
                        end
                    end
                end
            end
            
        end

        % Callback function: FileImportButton, ImportdataMenu
        function cbFileImport(app, event)
            % Import data into App workspace
            
            filefilter = {'*.txt;*.dat;*.csv', 'Delimited Text Files (*.txt,*.dat,*.csv)'
                '*.xls;*.xlsx;*.xlsm;*.ods', 'Spreadsheet Files (*.xls,*.xlsx,*.xlsm,*.ods)'
                '*.txt;*.asc', 'Jasco ASCII Files (*.txt,*.asc)'
                '*.txt;*.csv', 'Chirascan CSV Files (*.txt,*.csv)'
                '*.txt', 'OceanOptics SpectraSuite Files (*.txt)'
                '*.txt;*.dat', 'Number-only ASCII Files (*.txt,*.dat)'
                '*.*',  'Generic Text Files - ignore header (*.*)'};
            
            filetypes = {'text','spreadsheet','JWS','Chirascan','SpectraSuite','XY','ASCII'};
            
            
            % Get file name
            [FileNames,FilePath,FilterIndex] = uigetfile(filefilter,'Select files','MultiSelect','on');
            drawnow;
            app.SpectrOMaticUIFigure.Visible = 'off';
            app.SpectrOMaticUIFigure.Visible = 'on';
            
            if FilePath
                cd(FilePath)
            end
            if FilterIndex == 0, return; end
            if ~iscell(FileNames)
                FileNames = {FileNames};
            end
            FullNames = FileNames;
            for k = 1:numel(FileNames)
                FullNames{k} = [FilePath, FileNames{k}];
            end
            
            % Load Data
            try
                data = specdata.load(FullNames,'FileType',filetypes{FilterIndex});
                data = reshape(data,numel(data),1);
                if isfield(app.Groups,'data')
                    app.Groups = rmfield(app.Groups,'data');
                end
                success = true;
            catch ME
                msgbox(ME.message,'Data Import Failed')
                success = false;
            end
            fclose('all');
            
            % Find a free object name and store data
            if success
                if ~isfield(app.Data,'data')
                    app.Data.data = data;
                else
                    n = 0;
                    while n<1000
                        n = n + 1;
                        newname = sprintf('data%d',n);
                        if ~isfield(app.Data,newname)
                            app.Data.(newname) = data;
                            break
                        end
                    end
                end
                
                resetDataComponents(app,event)
            end
        end

        % Callback function: FileLoadButton, LoadmatfileMenu
        function cbFileLoad(app, event)
            % LOADDATA Load data from .MAT file into App workspace
            % Get file name
            [FileName,FilePath] = uigetfile('*.mat','Select MATLAB data file');
            drawnow;
            app.SpectrOMaticUIFigure.Visible = 'off';
            app.SpectrOMaticUIFigure.Visible = 'on';
            
            if FilePath
                cd(FilePath)
            end
            
            % Load File
            if FileName
                FileData = load([FilePath,FileName]);
                
                % If Data variable exists, load that, otherwise, load any specdata variable
                if isfield(FileData,'Data') && isstruct(FileData.Data)
                    datafields = fieldnames(FileData.Data);
                    for f = 1:numel(datafields)
                        app.Data.(datafields{f}) = FileData.Data.(datafields{f});
                    end
                    if isfield(FileData,'Groups') && isstruct(FileData.Groups)
                        groupfields = fieldnames(FileData.Groups);
                        for f = 1:numel(groupfields)
                            app.Groups.(groupfields{f}) = FileData.Groups.(groupfields{f});
                        end
                    end
                    if isfield(FileData,'History') && isstruct(FileData.History)
                        histfields = fieldnames(FileData.Groups);
                        for f = 1:numel(histfields)
                            app.History.(histfields{f}) = FileData.History.(histfields{f});
                        end
                    end
                    
                else
                    DataFields = fieldnames(FileData);
                    for f = 1:numel(DataFields)
                        fname = DataFields{f};
                        DataSet = [];
                        if isa(FileData.(fname),'specdata') || isa(FileData.(fname),'specdata2D') || isnumeric(FileData.(fname))
                            % Load specdata
                            DataSet = FileData.(fname);
                            if isa(DataSet,'specdata2D')
                                DataSet = specdata(DataSet);
                                DataSet = DataSet(:);
                            end
                        elseif isstruct(FileData.(fname))
                            % Load struct
                            DataStruct = FileData.(fname);
                            flist = fieldnames(DataStruct);
                            if all(ismember({'X','Y'},flist))
                                % Load specdata from struct
                                DataSet = specdata(DataStruct);
                                
                            elseif isscalar(DataStruct) && all(ismember({'wl','tau','das'},flist))
                                % Load DAS struct
                                DataSet2 = specdata2D(DataStruct.wl, round(DataStruct.tau,3), DataStruct.das, 'DAS', ...
                                    'TType', 'Lifetime', 'YType', 'DAS', 'XType', 'Wavelength', 'XUnit', 'nm');
                                if ismember('tunit',flist)
                                    DataSet2.TUnit = DataStruct.tunit;
                                end
                                DataSet2.ExpID = regexprep(FileName,'.mat$','');
                                DataSet = specdata(DataSet2)';
                                
                                if ismember('sas',flist)
                                    % Load SAS struct
                                    DataSet2.Y = DataStruct.sas;
                                    DataSet2.T = 1:size(DataStruct.sas,2);
                                    DataSet2.TUnit = '';
                                    DataSet2.YType = 'SAS'; DataSet2.ID = 'SAS';
                                    DataSet1 = specdata(DataSet2)';
                                    if ismember('species',flist)
                                        DataSet1 = DataSet1.set('ID','species');
                                    end
                                    DataSet = [DataSet; DataSet1];
                                end
                                
                            elseif isscalar(DataStruct) && all(ismember({'wl','t','decays'},flist))
                                % Load time-resolved data
                                if numel(DataStruct.t) > numel(DataStruct.wl)
                                    DataSet = specdata2D(DataStruct.t, DataStruct.wl, DataStruct.decays, fname, ...
                                        'ExpID', FileName, 'XType', 'Time', 'TType', 'Wavelength');
                                else
                                    DataSet = specdata2D(DataStruct.wl, DataStruct.t, DataStruct.decays', fname, ...
                                        'ExpID', FileName, 'XType', 'Wavelength', 'TType', 'Time');
                                end
                                DataSet = specdata(DataSet)';
                            end
                        end %if isa(...
                        
                        if ~isempty(DataSet)
                            % Find a free name to store the object
                            if ~isfield(app.Data,fname)
                                app.Data.(fname) = DataSet;
                            else
                                n = 0;
                                while 1
                                    n = n + 1;
                                    newname = sprintf('%s%d',fname,n);
                                    if ~isfield(app.Data,newname)
                                        app.Data.(newname) = DataSet;
                                        break %while
                                    end %if
                                end %while
                            end %if ~isfield(...
                        end %if .
                    end % for f
                end %isfield
                
                % Refresh UI components
                resetDataComponents(app,event)
                cbMathClearButton(app,event);
            end
            
        end

        % Callback function: FileSaveButton, SavematfileMenu
        function cbFileSave(app, event)
            [FileName,FilePath] = uiputfile('*.mat','Select MATLAB data file');
            drawnow;
            app.SpectrOMaticUIFigure.Visible = 'off';
            app.SpectrOMaticUIFigure.Visible = 'on';
            
            if isequal(FileName,0) || isequal(FilePath,0)
                return
            end
            FileOut = [FilePath,FileName];
            Data = app.Data; Groups = app.Groups; History = app.History;
            save(FileOut,'Data','Groups','History');
        end

        % Button pushed function: FindAddButton
        function cbFindAdd(app, event)
            sel = cell2mat(app.DataTable.Data(:,1));
            res = FindFind(app);
            sel = sel | res;
            app.DataTable.Data(:,1) = num2cell(sel);
            cbDataTableChanged(app, event);
        end

        % Button pushed function: FindMultiplyButton
        function cbFindMultiply(app, event)
            sel = cell2mat(app.DataTable.Data(:,1));
            res = FindFind(app);
            sel = sel & res;
            app.DataTable.Data(:,1) = num2cell(sel);
            cbDataTableChanged(app, event)
        end

        % Button pushed function: FindSubtractButton
        function cbFindSubtract(app, event)
            sel = cell2mat(app.DataTable.Data(:,1));
            res = FindFind(app);
            sel = sel & ~res;
            app.DataTable.Data(:,1) = num2cell(sel);
            cbDataTableChanged(app, event)
        end

        % Value changed function: LegendButton
        function cbLegendButton(app, event)
            if app.LegendButton.Value
                legend(app.BrowserFigure,'show','location','best')
            else
                legend(app.BrowserFigure,'off')
            end
            
        end

        % Button pushed function: absButton
        function cbMathAbs(app, event)
            MathBeginOp(app, @abs, 'abs(%s)', [])
        end

        % Button pushed function: CEButton
        function cbMathCE(app, event)
            if app.OpPending
                app.CommandField.Value = '';
                app.ComText = app.ComTextOld;
                app.OpFunc = []; app.OpFuncParam = [];
                app.OpPending = false;
            elseif app.OpBinary
                app.CommandField.Value = '';
                app.ComText = app.ComTextOld;
                app.ComTextB = '';
                app.RegA = app.RegB; app.GrpA = app.GrpB; app.HstA = app.HstB;
                app.RegB = []; app.GrpB = struct; app.HstB = {};
                app.OpBinary = []; app.OpBinaryFunc = [];
            end
            updateCALInfoBox(app)
        end

        % Button pushed function: ClearButton
        function cbMathClearButton(app, event)
            app.ComText = ''; app.ComTextB = '';
            app.RegA = []; app.RegB = [];
            app.GrpA = struct; app.GrpB = struct;
            app.HstA = {}; app.HstB = {};
            app.OpPending = false; app.OpBinary = false;
            updateCALInfoBox(app)
            app.SelectDataSwitch.Value = 'SEL';
            cbSelectDataSwitch(app, event);
        end

        % Callback function
        function cbMathDataDropDown(app, event)
            var = app.MathDataDropDown.Value;
            if isfield(app.Groups,var)
                app.MathGroupDropDown.Items = [{'<all data>'}; fieldnames(app.Groups.(var))];
            else
                app.MathGroupDropDown.Items = {'<all data>'};
            end
            cbMathGroupDropDown(app, event)
        end

        % Button pushed function: diffButton
        function cbMathDiff(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                MathBeginOp(app, @diff, 'diff(%s)', [])
            end
        end

        % Button pushed function: MDivideButton
        function cbMathDivide(app, event)
            MathBeginBinaryOp(app, @rdivide, '%s / ')
        end

        % Button pushed function: ExecuteButton
        function cbMathEnter(app, event)
            if isempty(app.RegA)
                cbMathEnterObject(app, event)
            end
            if ~isempty(app.RegA)
                if app.OpPending, MathExecute(app, 1), end
                if app.OpBinary, MathExecuteBinary(app, 1), end
                cbSelectDataSwitch(app, event)
            end
        end

        % Button pushed function: SelectEnterButton
        function cbMathEnterObject(app, event)
            % Copy Selection to Calculator
            var = app.SelectDataDropDown.Value;
            if isempty(var), return, end
            app.GrpA = struct;
            if isa(app.Data.(var),'specdata')
                % Selection is specdata
                sel = selectgroup(app);
                if ~any(sel)
                    % Nothing selected
                    cbSelectDataSwitch(app, event)
                    return
                end
                vartxt = getObjectName(app, var, sel);
                Value = app.Data.(var)(sel);
                if isfield(app.Groups,var)
                    app.GrpA = getSelectedGroups(app, var, sel);
                end
            elseif isnumeric(app.Data.(var))
                % Selection is numeric
                Value = app.Data.(var);
                if numel(Value)==1
                    vartxt = num2str(Value);
                else
                    vartxt = var;
                end
                if isfield(app.Groups,var)
                    app.GrpA = app.Groups.(var);
                end
            else
                cbSelectDataSwitch(app, event)
                return
            end
            app.HstA = app.History.(var);
            if app.OpPending
                cbMathCE(app, event)
            end
            app.ComText = vartxt;
            app.RegA = Value;
            updateCALInfoBox(app);
        end

        % Button pushed function: ScalarEnterButton
        function cbMathEnterScalar(app, event)
            if app.OpPending
                cbMathCE(app, event)
            end
            app.RegA = app.MathScalarSpinner.Value;
            app.HstA = {};
            app.ComText = num2str(app.RegA);
            updateCALInfoBox(app);
        end

        % Callback function
        function cbMathGroupDropDown(app, event)
            cbMathEnterObject(app, event)
        end

        % Button pushed function: areaButton
        function cbMathInt(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                range = [app.MathRange1.Value app.MathRange2.Value];
                if any(range)
                    MathBeginOp(app, @int, '%s.int([%s])', range)
                else
                    MathBeginOp(app, @int, '%s.int', [])
                end
            end
            
        end

        % Button pushed function: lnButton
        function cbMathLog(app, event)
            MathBeginOp(app, @log, 'log(%s)', [])
        end

        % Button pushed function: log10Button
        function cbMathLog10(app, event)
            MathBeginOp(app, @log10, 'log10(%s)', [])
        end

        % Button pushed function: maxButton
        function cbMathMax(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                range = [app.MathRange1.Value app.MathRange2.Value];
                if any(range)
                    MathBeginOp(app, @max, '%s.max([%s])', range)
                else
                    MathBeginOp(app, @max, '%s.max', [])
                end
            end
        end

        % Button pushed function: meanButton
        function cbMathMean(app, event)
            MathBeginOp(app, @mean, 'mean(%s)', [])
        end

        % Button pushed function: mergeButton
        function cbMathMerge(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                MathBeginOp(app, @merge, 'merge(%s)', [])
            end
        end

        % Button pushed function: minButton
        function cbMathMin(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                range = [app.MathRange1.Value app.MathRange2.Value];
                if any(range)
                    MathBeginOp(app, @min, '%s.min([%s])', range)
                else
                    MathBeginOp(app, @min, '%s.min', [])
                end
            end
        end

        % Button pushed function: MinusButton
        function cbMathMinus(app, event)
            MathBeginBinaryOp(app, @minus, '%s - ')
        end

        % Button pushed function: MTimesButton
        function cbMathMultiply(app, event)
            MathBeginBinaryOp(app, @times, '%s * ')
        end

        % Button pushed function: NegateButton
        function cbMathNegate(app, event)
            MathBeginOp(app, @uminus, '-%s', [])
        end

        % Button pushed function: normButton
        function cbMathNorm(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                range = [app.MathRange1.Value app.MathRange2.Value];
                if ~any(range)
                    MathBeginOp(app, @norm, '%s.norm', [])
                elseif ~range(2)
                    MathBeginOp(app, @norm, '%s.norm(%s)', range(1))
                else
                    MathBeginOp(app, @norm, '%s.norm([%s])', range)
                end
            end
        end

        % Button pushed function: PlusButton
        function cbMathPlus(app, event)
            MathBeginBinaryOp(app, @plus, '%s + ')
        end

        % Button pushed function: Button
        function cbMathPower(app, event)
            MathBeginBinaryOp(app, @power, '%s ^ ')
        end

        % Button pushed function: smoothButton
        function cbMathSmooth(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                Value = app.MathRange1.Value;
                if Value == 0, Value = 5; end
                MathBeginOp(app, @smooth, '%s.smooth(%s)', Value)
            end
        end

        % Button pushed function: sumButton
        function cbMathSum(app, event)
            MathBeginOp(app, @sum, 'sum(%s)', [])
        end

        % Button pushed function: XlimButton
        function cbMathYlim(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                MathBeginOp(app, @setxlim, '%s.setxlim([%s])', [app.MathRange1.Value app.MathRange2.Value])
            end
        end

        % Button pushed function: YXButton
        function cbMathYx(app, event)
            if isempty(app.RegA), cbMathEnterObject(app, event), end
            if isa(app.RegA,'specdata')
                MathBeginOp(app, @Yx, '%s.Yx(%s)', app.MathRange1.Value)
            end
        end

        % Callback function
        function cbPanButton(app, event)
            
        end

        % Button pushed function: PeaksButton
        function cbPeaksButton(app, event)
            % Mark Peaks on Figure
            c = app.BrowserFigure.Children;
            if ~isempty(c)
                delete(app.PlotPeakLabels)
                app.PlotPeakLabels = [];
                for k = 1:numel(c)
                    if isa(c(k),'matlab.graphics.chart.primitive.Line')
                        X = c(k).XData; Y = c(k).YData;
                        dy = (max(Y) - min(Y)) / 10;
                        % dx = (max(X) - min(X)) / 15;
                        % [py,px] = findpeaks(Y, X, 'MinPeakHeight', dy, 'MinPeakDistance', dx);
                        [pmax, pmin]= peakdet(Y, dy, X);
                        peaks = [pmax; pmin]; ypos = [pmax(:,2) + dy/5; pmin(:,2) - dy/5];
                        px = peaks(:,1); py = peaks(:,2);
                        for l = 1:numel(py)
                            h = text(app.BrowserFigure,px(l),ypos(l),sprintf('%0.3g,%0.3g',px(l),py(l)),'HorizontalAlignment','center','Color',c(k).Color);
                            app.PlotPeakLabels = [app.PlotPeakLabels, h];
                        end
                    end
                end
                % app.PeaksButton.Enable = 'off';
            end
        end

        % Callback function: BrowserTabGroup, PlotDataListBox
        function cbPlotDataListBox(app, event)
            refreshBrowser(app)
        end

        % Value changed function: FindField
        function cbRefreshFindKeys(app, event)
            fld = app.FindField.Value;
            var = app.SelectDataDropDown.Value;
            if isempty(var), return, end
            if strcmp(fld,'Group')
                if isfield(app.Groups,var)
                    keys = fieldnames(app.Groups.(var));
                else
                    keys = {};
                    app.ContainsDropDown.Enable = 'off';
                end
            else
                app.ContainsDropDown.Enable = 'on';
                if ~isempty(app.Data.(var)) && isa(app.Data.(var),'specdata')
                    keys =  {app.Data.(var).(fld)};
                    keys = unique(keys);
                else
                    keys = {};
                    app.ContainsDropDown.Enable = 'off';
                end
            end
            app.ContainsDropDown.Items = keys;
            if numel(keys) == 1
                app.ContainsDropDown.Value = keys{1};
            else
                app.ContainsDropDown.Value = '';
            end
        end

        % Button pushed function: SeletAllButton
        function cbSelectAll(app, event)
            n = size(app.DataTable.Data,1);
            app.DataTable.Data(:,1) = num2cell(true(n,1));
            cbDataTableChanged(app, event)
        end

        % Value changed function: SelectDataDropDown
        function cbSelectDataDropDown(app, event)
            var = app.SelectDataDropDown.Value;
            if isfield(app.Groups,var)
                app.SelectGroupDropDown.Items = [{'<all data>'}; fieldnames(app.Groups.(var))];
            else
                app.SelectGroupDropDown.Items = {'<all data>'};
            end
            refreshDataTable(app);
            cbRefreshFindKeys(app, event)
            cbSelectDataSwitch(app, event)
        end

        % Value changed function: SelectDataSwitch
        function cbSelectDataSwitch(app, event)
            switch app.SelectDataSwitch.Value
                case 'SEL'
                    var = app.SelectDataDropDown.Value;
                    if isempty(var)
                        app.SelectSaveObjectButton.Enable = 'off';
                        app.SelectSaveGroupButton.Enable = 'off';
                        app.SelectEnterButton.Enable = 'off';
                    elseif isa(app.Data.(var), 'specdata')
                        sel = selectgroup(app);
                        if any(sel)
                            app.SelectSaveObjectButton.Enable = 'on';
                            app.SelectSaveGroupButton.Enable = 'on';
                            app.SelectEnterButton.Enable = 'on';
                        else
                            app.SelectSaveObjectButton.Enable = 'off';
                            app.SelectSaveGroupButton.Enable = 'off';
                            app.SelectEnterButton.Enable = 'off';
                        end
                    else
                        app.SelectSaveObjectButton.Enable = 'off';
                        app.SelectSaveGroupButton.Enable = 'off';
                        app.SelectEnterButton.Enable = 'on';
                    end
                case 'CAL'
                    if ~isempty(app.RegA) && app.OpPending==false
                        % app.SelectEnterButton.Enable = 'on';
                        app.SelectSaveObjectButton.Enable = 'on';
                        app.SelectSaveGroupButton.Enable = 'off';
                    end
                    app.SelectEnterButton.Enable = 'off';
            end
        end

        % Value changed function: SelectGroupDropDown
        function cbSelectGroupDropDown(app, event)
            refreshDataTable(app);
        end

        % Button pushed function: NoneButton
        function cbSelectNone(app, event)
            n = size(app.DataTable.Data,1);
            app.DataTable.Data(:,1) = num2cell(false(n,1));
            cbDataTableChanged(app, event)
        end

        % Button pushed function: SelectSaveGroupButton
        function cbSelectSaveGroupButton(app, event)
            % Save current selection as a new group
            var = app.SelectDataDropDown.Value;
            newgroup = app.SelectSaveNameEditField.Value;
            newgroup = matlab.lang.makeValidName(newgroup);
            app.SelectSaveNameEditField.Value = newgroup;
            sel = selectgroup(app);
            app.Groups.(var).(newgroup) = sel;
            resetDataComponents(app, event);
        end

        % Button pushed function: SelectSaveObjectButton
        function cbSelectSaveObjectButton(app, event)
            var = app.SelectDataDropDown.Value;
            newvar = app.SelectSaveNameEditField.Value;
            newvar = matlab.lang.makeValidName(newvar);
            app.SelectSaveNameEditField.Value = newvar;
            
            switch app.SelectDataSwitch.Value
                case 'SEL'
                    % Copy Data Selection
                    sel = selectgroup(app);
                    app.Data.(newvar) = app.Data.(var)(sel);
                    
                    % Copy existing groups
                    if isfield(app.Groups,var)
                        app.Groups.(newvar) = getSelectedGroups(app, var, sel);
                    end
                    app.History.(newvar) = app.History.(var);
                    app.History.(newvar){end+1} = [newvar, ' = ', getObjectName(app, var, sel)];
                case 'CAL'
                    % Copy Calculation Result
                    if app.OpPending || app.OpBinary
                        MathExecute(app, 0)
                    end
                    if isempty(app.RegA) || isempty(newvar), return, end
                    app.Data.(newvar) = app.RegA;
                    if ~isempty(app.GrpA)
                        app.Groups.(newvar) = app.GrpA;
                    end
                    % app.History.(newvar) = app.HstA;
                    % if isempty(app.History.(newvar)), app.History.(newvar) = {''}; end
                    app.History.(newvar) = [app.HstA, {[newvar, ' = ', app.CommandField.Value]}];
            end
            
            
            % Update components
            resetDataComponents(app, event)
            app.DatasetObjectsListBox.Value = newvar;
            cbDatasetObjectsListBox(app, event)
        end

        % Selection change function: TabGroup
        function cbTabGroupChanged(app, event)
            if isempty(app.DatasetObjectsListBox.Value), return, end
            switch app.TabGroup.SelectedTab.Title
                case 'Data Browser'
                    if ~strcmp(app.DatasetObjectsListBox.Value{1}, app.SelectDataDropDown.Value)
                        app.DatasetObjectsListBox.Value = {app.SelectDataDropDown.Value};
                        cbDatasetObjectsListBox(app, event)
                    else
                        refreshBrowser(app)
                    end
                case 'Calculator'
                    if ~strcmp(app.DatasetObjectsListBox.Value{1}, app.SelectDataDropDown.Value)
                        app.SelectDataDropDown.Value = app.DatasetObjectsListBox.Value{1};
                        cbSelectDataDropDown(app, event)
                    end
            end
            
        end

        % Callback function: TransferalltoMATLABMenu, WSExportButton
        function cbWSExport(app, event)
            assignin('base','Data',app.Data);
            assignin('base','Groups',app.Groups);
            assignin('base','History',app.History);
        end

        % Callback function: TransferselectedMenu, 
        % WSExportSelectionButton
        function cbWSExportSelection(app, event)
            var = app.DatasetObjectsListBox.Value;
            if isempty(var), return, end
            for k = 1:numel(var)
                assignin('base',var{k},app.Data.(var{k}));
                if isfield(app.Groups,var{k}) && ~isempty(fieldnames(app.Groups.(var{k})))
                    assignin('base',['ix',var{k}],app.Groups.(var{k}));
                end
            end
        end

        % Callback function
        function cbZoomButton(app, event)

        end

        % Callback function: CopySpectraButton, CopytoclipboardMenu
        function cbCopySpectra(app, event)
            % Copy data to clipboard
            sel = app.PlotDataListBox.Value;
            if ~isempty(app.PlotData) && ~isempty(sel)
                % Copy spectra
                dat = app.PlotData(sel);
                nl = newline;
                % Check if all spectra have equal X
                for k = 1:numel(dat)
                    if ~isequal(dat(k).X,dat(1).X)
                        warning('Cannot copy. The spectra have different X axes.')
                        return
                    end
                end
                % create header
                hdr = dat(1).XType; hdr1 = '';
                for k = 1:numel(dat)
                    hdr1 = [hdr1, sprintf('\t%s',dat(k).ID)];
                end
                hdr = [hdr,hdr1,nl];
                % add X,Y values
                dstr = '';
                for x = 1:numel(dat(1).X)
                    dx = sprintf('%g',dat(1).X(x)');
                    dstr1 = '';
                    for k = 1:numel(dat)
                        dstr1 = [dstr1, sprintf('\t%g',dat(k).Y(x))];
                    end
                    dstr = [dstr,dx,dstr1,nl];
                end
                
                dstr = [hdr,dstr];
                clipboard('copy',dstr)
            else
                % Copy scalars
                vars = app.DatasetObjectsListBox.Value;
                if numel(vars)==1
                    varmat = app.Data.(vars{1});
                    varstr = mat2str(varmat);
                    varstr = varstr(2:end-1);
                    varstr = strrep(varstr,';',newline);
                    varstr = strrep(varstr,' ',char(9));
                    clipboard('copy',varstr)
                end
                
            end
        end

        % Button pushed function: FigSaveButton
        function FigSaveButtonPushed(app, event)
            filter = {'*.png','Portable Network Graphics file (*.png)';'*.jpg','JPEG image (*.jpg)';...
                      '*.tif','TIFF image (*.tif)';'*.pdf','Adobe PDF (*.pdf)'};
            [FileName,FilePath] = uiputfile(filter,'Export figure');
            if isequal(FileName,0) || isequal(FilePath,0)
                return
            end
            FileOut = [FilePath,FileName];
            exportgraphics(app.BrowserFigure,FileOut)
        end

        % Button pushed function: FigCopyButton
        function FigCopyButtonPushed(app, event)
            copygraphics(app.BrowserFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrOMaticUIFigure = uifigure('Visible', 'off');
            app.SpectrOMaticUIFigure.Name = 'Spectr-O-Matic';
            app.FileMenu = uimenu(app.SpectrOMaticUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadmatfileMenu = uimenu(app.FileMenu);
            app.LoadmatfileMenu.MenuSelectedFcn = createCallbackFcn(app, @cbFileLoad, true);
            app.LoadmatfileMenu.Text = 'Load .mat file...';
            app.SavematfileMenu = uimenu(app.FileMenu);
            app.SavematfileMenu.MenuSelectedFcn = createCallbackFcn(app, @cbFileSave, true);
            app.SavematfileMenu.Text = 'Save .mat file...';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cbFileImport, true);
            app.ImportdataMenu.Text = '&Import data...';
            app.ExportdataMenu = uimenu(app.FileMenu);
            app.ExportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cbFileExport, true);
            app.ExportdataMenu.Text = 'E&xport data...';
            app.CopytoclipboardMenu = uimenu(app.FileMenu);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @cbCopySpectra, true);
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.WorkspaceMenu = uimenu(app.SpectrOMaticUIFigure);
            app.WorkspaceMenu.Text = 'Workspace';
            app.TransferalltoMATLABMenu = uimenu(app.WorkspaceMenu);
            app.TransferalltoMATLABMenu.MenuSelectedFcn = createCallbackFcn(app, @cbWSExport, true);
            app.TransferalltoMATLABMenu.Text = 'Transfer all to MATLAB';
            app.TransferselectedMenu = uimenu(app.WorkspaceMenu);
            app.TransferselectedMenu.MenuSelectedFcn = createCallbackFcn(app, @cbWSExportSelection, true);
            app.TransferselectedMenu.Text = 'Transfer selected';
            app.TabGroup = uitabgroup(app.SpectrOMaticUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @cbTabGroupChanged, true);
            app.DataBrowserTab = uitab(app.TabGroup);
            app.DataBrowserTab.Title = 'Data Browser';
            app.FileIOPanel = uipanel(app.DataBrowserTab);
            app.FileIOPanel.Title = 'File I/O';
            app.FileImportButton = uibutton(app.FileIOPanel, 'push');
            app.FileImportButton.ButtonPushedFcn = createCallbackFcn(app, @cbFileImport, true);
            app.FileImportButton.Icon = 'import_ts_16.png';
            app.FileImportButton.Tooltip = {'Import data from text or spreadsheet files'};
            app.FileImportButton.Text = 'Import';
            app.FileExportButton = uibutton(app.FileIOPanel, 'push');
            app.FileExportButton.ButtonPushedFcn = createCallbackFcn(app, @cbFileExport, true);
            app.FileExportButton.Icon = 'save_all_ts_16.png';
            app.FileExportButton.Tooltip = {'Export selected data to a text or spreadsheet file'};
            app.FileExportButton.Text = 'Export';
            app.FileLoadButton = uibutton(app.FileIOPanel, 'push');
            app.FileLoadButton.ButtonPushedFcn = createCallbackFcn(app, @cbFileLoad, true);
            app.FileLoadButton.Icon = 'open_ts_16.png';
            app.FileLoadButton.Tooltip = {'Load data from a .mat file'};
            app.FileLoadButton.Text = 'Load';
            app.FileSaveButton = uibutton(app.FileIOPanel, 'push');
            app.FileSaveButton.ButtonPushedFcn = createCallbackFcn(app, @cbFileSave, true);
            app.FileSaveButton.Icon = 'save_ts_16.png';
            app.FileSaveButton.Tooltip = {'Save data to a .mat file'};
            app.FileSaveButton.Text = 'Save';
            app.CopySpectraButton = uibutton(app.FileIOPanel, 'push');
            app.CopySpectraButton.ButtonPushedFcn = createCallbackFcn(app, @cbCopySpectra, true);
            app.CopySpectraButton.Icon = 'edit_copy.png';
            app.CopySpectraButton.Tooltip = {'Copy selected data to the clipboard'};
            app.CopySpectraButton.Text = 'Copy';
            app.MATLABWorkspacePanel = uipanel(app.DataBrowserTab);
            app.MATLABWorkspacePanel.Title = 'MATLAB Workspace';
            app.WSExportSelectionButton = uibutton(app.MATLABWorkspacePanel, 'push');
            app.WSExportSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @cbWSExportSelection, true);
            app.WSExportSelectionButton.Icon = 'new_variable_ts_16.png';
            app.WSExportSelectionButton.Tooltip = {'Transfer selected data to the MATLAB workspace'};
            app.WSExportSelectionButton.Text = 'Selected';
            app.WSExportButton = uibutton(app.MATLABWorkspacePanel, 'push');
            app.WSExportButton.ButtonPushedFcn = createCallbackFcn(app, @cbWSExport, true);
            app.WSExportButton.Icon = 'new_variable_ts_16.png';
            app.WSExportButton.Tooltip = {'Transfer all data to the MATLAB workspace'};
            app.WSExportButton.Text = 'All Data';
            app.BrowserTabGroup = uitabgroup(app.DataBrowserTab);
            app.BrowserTabGroup.SelectionChangedFcn = createCallbackFcn(app, @cbPlotDataListBox, true);
            app.FigureTab = uitab(app.BrowserTabGroup);
            app.FigureTab.Title = 'Figure';
            app.BrowserFigure = uiaxes(app.FigureTab);
            xlabel(app.BrowserFigure, 'X')
            ylabel(app.BrowserFigure, 'Y')
            app.TableTab = uitab(app.BrowserTabGroup);
            app.TableTab.Title = 'Table';
            app.BrowserTable = uitable(app.TableTab);
            app.BrowserTable.ColumnName = '';
            app.BrowserTable.RowName = {};
            app.HistoryTab = uitab(app.BrowserTabGroup);
            app.HistoryTab.Title = 'History';
            app.OperationshistoryTextAreaLabel = uilabel(app.HistoryTab);
            app.OperationshistoryTextAreaLabel.Text = 'Operations history';
            app.HistoryTextBox = uitextarea(app.HistoryTab);
            app.HistoryTextBox.Editable = 'off';
            app.ObjectsPanel = uipanel(app.DataBrowserTab);
            app.ObjectsPanel.Tooltip = {'Objects are containers for data (spectra or scalars).'};
            app.ObjectsPanel.Title = 'Objects';
            app.DatasetRenameObjectButton = uibutton(app.ObjectsPanel, 'push');
            app.DatasetRenameObjectButton.ButtonPushedFcn = createCallbackFcn(app, @cbDatasetRenameObject, true);
            app.DatasetRenameObjectButton.Icon = 'variable_editor_16.png';
            app.DatasetRenameObjectButton.Tooltip = {'Rename or combine the selected object(s)'};
            app.DatasetRenameObjectButton.Text = 'Rename';
            app.DatasetDeleteObjectButton = uibutton(app.ObjectsPanel, 'push');
            app.DatasetDeleteObjectButton.ButtonPushedFcn = createCallbackFcn(app, @cbDatasetDeleteObject, true);
            app.DatasetDeleteObjectButton.Icon = 'edit_delete.png';
            app.DatasetDeleteObjectButton.Tooltip = {'Delete the selected object(s)'};
            app.DatasetDeleteObjectButton.Text = 'Delete';
            app.DataSetCopyObjectButton = uibutton(app.ObjectsPanel, 'push');
            app.DataSetCopyObjectButton.ButtonPushedFcn = createCallbackFcn(app, @cbDatasetCopyObject, true);
            app.DataSetCopyObjectButton.Icon = 'edit_copy.png';
            app.DataSetCopyObjectButton.Tooltip = {'Copy or combine the selected object(s)'};
            app.DataSetCopyObjectButton.Text = 'Duplicate';
            app.DatasetObjectsListBox = uilistbox(app.ObjectsPanel);
            app.DatasetObjectsListBox.Items = {};
            app.DatasetObjectsListBox.Multiselect = 'on';
            app.DatasetObjectsListBox.ValueChangedFcn = createCallbackFcn(app, @cbDatasetObjectsListBox, true);
            app.DatasetObjectsListBox.Tooltip = {'List of data objects. Hold Control or Shift for multiple selection.'};
            app.DatasetObjectsListBox.Value = {};
            app.GroupsPanel = uipanel(app.DataBrowserTab);
            app.GroupsPanel.Tooltip = {'An index of spectra in an object, grouped by attributes'};
            app.GroupsPanel.Title = 'Groups';
            app.AutoButton = uibutton(app.GroupsPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @cbAutoindex, true);
            app.AutoButton.Icon = 'database_app_16.png';
            app.AutoButton.Tooltip = {'Automatically create groups by the selected attribute'};
            app.AutoButton.Text = 'Auto';
            app.DeleteGroupButton = uibutton(app.GroupsPanel, 'push');
            app.DeleteGroupButton.ButtonPushedFcn = createCallbackFcn(app, @cbDeleteGroup, true);
            app.DeleteGroupButton.Icon = 'edit_delete.png';
            app.DeleteGroupButton.Tooltip = {'Delete the selected groups. The actual data are not deleted.'};
            app.DeleteGroupButton.Text = 'Delete';
            app.AutoIndexDropDown = uidropdown(app.GroupsPanel);
            app.AutoIndexDropDown.Items = {'ID', 'ExpID', 'XType', 'YType'};
            app.AutoIndexDropDown.Tooltip = {'Select an attribute and press ''Auto'' to automatically create groups'};
            app.AutoIndexDropDown.Value = 'ID';
            app.DatasetGroupsListBox = uilistbox(app.GroupsPanel);
            app.DatasetGroupsListBox.Items = {};
            app.DatasetGroupsListBox.Multiselect = 'on';
            app.DatasetGroupsListBox.ValueChangedFcn = createCallbackFcn(app, @cbDatasetGroupsListBox, true);
            app.DatasetGroupsListBox.Tooltip = {'Groups in the selected object(s). Hold Control or Shift for multiple selection.'};
            app.DatasetGroupsListBox.Value = {};
            app.SpectraPanel = uipanel(app.DataBrowserTab);
            app.SpectraPanel.Tooltip = {'A list of spectra in the selected object(s) and group(s)'};
            app.SpectraPanel.Title = 'Spectra';
            app.PlotDataListBox = uilistbox(app.SpectraPanel);
            app.PlotDataListBox.Items = {};
            app.PlotDataListBox.Multiselect = 'on';
            app.PlotDataListBox.ValueChangedFcn = createCallbackFcn(app, @cbPlotDataListBox, true);
            app.PlotDataListBox.Tooltip = {'Select one or more (hold Control or Shift) spectra to plot.'};
            app.PlotDataListBox.Value = {};
            app.FigurecontrolPanel = uipanel(app.DataBrowserTab);
            app.FigurecontrolPanel.Title = 'Figure control';
            app.HoldButton = uibutton(app.FigurecontrolPanel, 'state');
            app.HoldButton.Tooltip = {'Hold the plotted spectra (press again to clear)'};
            app.HoldButton.Icon = 'pan.png';
            app.HoldButton.Text = 'Hold';
            app.LegendButton = uibutton(app.FigurecontrolPanel, 'state');
            app.LegendButton.ValueChangedFcn = createCallbackFcn(app, @cbLegendButton, true);
            app.LegendButton.Tooltip = {'Toggle legend on|off'};
            app.LegendButton.Icon = 'legend.png';
            app.LegendButton.Text = '';
            app.PeaksButton = uibutton(app.FigurecontrolPanel, 'push');
            app.PeaksButton.ButtonPushedFcn = createCallbackFcn(app, @cbPeaksButton, true);
            app.PeaksButton.Icon = 'stats_app_16.png';
            app.PeaksButton.Tooltip = {'Display peak labels'};
            app.PeaksButton.Text = '';
            app.FigSaveButton = uibutton(app.FigurecontrolPanel, 'push');
            app.FigSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FigSaveButtonPushed, true);
            app.FigSaveButton.Icon = 'save_ts_16.png';
            app.FigSaveButton.Tooltip = {'Save figure'};
            app.FigSaveButton.Text = '';
            app.FigCopyButton = uibutton(app.FigurecontrolPanel, 'push');
            app.FigCopyButton.ButtonPushedFcn = createCallbackFcn(app, @FigCopyButtonPushed, true);
            app.FigCopyButton.Icon = 'edit_copy.png';
            app.FigCopyButton.Tooltip = {'Copy figure as bitmap'};
            app.FigCopyButton.Text = '';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Calculator';
            app.SELPanel = uipanel(app.DataTab);
            app.SELPanel.Title = 'SEL';
            app.DataTable = uitable(app.SELPanel);
            app.DataTable.ColumnName = {'Sel'; 'ID'; 'ExpID'; 'DateTime'; 'XType'; 'YType'; 'XUnit'; 'YUnit'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnEditable = [true true true false true true true true];
            app.DataTable.CellEditCallback = createCallbackFcn(app, @cbDataTableChanged, true);
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @cbDataTableSelection, true);
            app.PickObjectPanel = uipanel(app.SELPanel);
            app.PickObjectPanel.Title = 'Pick Object';
            app.ObjectDropDownLabel = uilabel(app.PickObjectPanel);
            app.ObjectDropDownLabel.Text = 'Object';
            app.SelectDataDropDown = uidropdown(app.PickObjectPanel);
            app.SelectDataDropDown.Items = {};
            app.SelectDataDropDown.ValueChangedFcn = createCallbackFcn(app, @cbSelectDataDropDown, true);
            app.SelectDataDropDown.Tooltip = {'Select data object to display'};
            app.SelectDataDropDown.Value = {};
            app.GroupDropDownLabel = uilabel(app.PickObjectPanel);
            app.GroupDropDownLabel.Text = 'Group';
            app.SelectGroupDropDown = uidropdown(app.PickObjectPanel);
            app.SelectGroupDropDown.Items = {'<all data>'};
            app.SelectGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @cbSelectGroupDropDown, true);
            app.SelectGroupDropDown.Tooltip = {'Display spectra only in the selected group'};
            app.SelectGroupDropDown.Value = '<all data>';
            app.SelectPanel = uipanel(app.SELPanel);
            app.SelectPanel.Title = 'Select';
            app.SeletAllButton = uibutton(app.SelectPanel, 'push');
            app.SeletAllButton.ButtonPushedFcn = createCallbackFcn(app, @cbSelectAll, true);
            app.SeletAllButton.Tooltip = {'Select all displayed spectra '};
            app.SeletAllButton.Text = 'All';
            app.NoneButton = uibutton(app.SelectPanel, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @cbSelectNone, true);
            app.NoneButton.Tooltip = {'Clear the selection'};
            app.NoneButton.Text = 'None';
            app.FinderButtonGroup = uibuttongroup(app.SELPanel);
            app.FinderButtonGroup.Title = 'Finder';
            app.FindAddButton = uibutton(app.FinderButtonGroup, 'push');
            app.FindAddButton.ButtonPushedFcn = createCallbackFcn(app, @cbFindAdd, true);
            app.FindAddButton.Tooltip = {'Select spectra with the chosen attribute'};
            app.FindAddButton.Text = 'Select';
            app.FindField = uidropdown(app.FinderButtonGroup);
            app.FindField.Items = {'ID', 'ExpID', 'DateTime', 'XType', 'YType', 'Group'};
            app.FindField.ValueChangedFcn = createCallbackFcn(app, @cbRefreshFindKeys, true);
            app.FindField.Tooltip = {'Choose an attribute to search by'};
            app.FindField.Value = 'ID';
            app.FindSubtractButton = uibutton(app.FinderButtonGroup, 'push');
            app.FindSubtractButton.ButtonPushedFcn = createCallbackFcn(app, @cbFindSubtract, true);
            app.FindSubtractButton.Tooltip = {'Unselect the spectra that match the search attribute'};
            app.FindSubtractButton.Text = 'Unselect';
            app.ContainsDropDown = uidropdown(app.FinderButtonGroup);
            app.ContainsDropDown.Items = {};
            app.ContainsDropDown.Editable = 'on';
            app.ContainsDropDown.ValueChangedFcn = createCallbackFcn(app, @cbContainsDropDown, true);
            app.ContainsDropDown.Tooltip = {'Type or choose a value to search for'};
            app.ContainsDropDown.Value = {};
            app.FindMultiplyButton = uibutton(app.FinderButtonGroup, 'push');
            app.FindMultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @cbFindMultiply, true);
            app.FindMultiplyButton.Tooltip = {'Refine selection (unselect spectra that do not match)'};
            app.FindMultiplyButton.Text = 'Refine';
            app.CALPanel = uipanel(app.DataTab);
            app.CALPanel.Title = 'CAL';
            app.SaveSelectionPanel = uipanel(app.CALPanel);
            app.SaveSelectionPanel.Title = 'Transfer';
            app.NameEditFieldLabel = uilabel(app.SaveSelectionPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.SelectSaveNameEditField = uieditfield(app.SaveSelectionPanel, 'text');
            app.SelectSaveNameEditField.Tooltip = {'Type name for a new object/group'};
            app.SelectSaveGroupButton = uibutton(app.SaveSelectionPanel, 'push');
            app.SelectSaveGroupButton.ButtonPushedFcn = createCallbackFcn(app, @cbSelectSaveGroupButton, true);
            app.SelectSaveGroupButton.Enable = 'off';
            app.SelectSaveGroupButton.Tooltip = {'Create a new group from the selected spectra'};
            app.SelectSaveGroupButton.Text = 'New group';
            app.SelectSaveObjectButton = uibutton(app.SaveSelectionPanel, 'push');
            app.SelectSaveObjectButton.ButtonPushedFcn = createCallbackFcn(app, @cbSelectSaveObjectButton, true);
            app.SelectSaveObjectButton.Enable = 'off';
            app.SelectSaveObjectButton.Tooltip = {'Create a new data object from SELection or CALculator memory'};
            app.SelectSaveObjectButton.Text = 'New object';
            app.SelectEnterButton = uibutton(app.SaveSelectionPanel, 'push');
            app.SelectEnterButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathEnterObject, true);
            app.SelectEnterButton.Tooltip = {'Enter selection into the calculator'};
            app.SelectEnterButton.Text = 'CAL';
            app.SelectDataSwitch = uiswitch(app.SaveSelectionPanel, 'slider');
            app.SelectDataSwitch.Items = {'SEL', 'CAL'};
            app.SelectDataSwitch.ValueChangedFcn = createCallbackFcn(app, @cbSelectDataSwitch, true);
            app.SelectDataSwitch.Enable = 'off';
            app.SelectDataSwitch.Tooltip = {'Use SELection table or CALculator memory'};
            app.SelectDataSwitch.Value = 'SEL';
            app.EnterScalarPanel = uipanel(app.CALPanel);
            app.EnterScalarPanel.Title = 'Enter scalar';
            app.ValueSpinnerLabel = uilabel(app.EnterScalarPanel);
            app.ValueSpinnerLabel.Text = 'Value';
            app.MathScalarSpinner = uispinner(app.EnterScalarPanel);
            app.MathScalarSpinner.Tooltip = {'Type value and press ''CAL'' to enter scalar (constant)'};
            app.ScalarEnterButton = uibutton(app.EnterScalarPanel, 'push');
            app.ScalarEnterButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathEnterScalar, true);
            app.ScalarEnterButton.Tooltip = {'Type value and press ''CAL'' to enter scalar (constant)'};
            app.ScalarEnterButton.Text = 'CAL';
            app.OperationPanel = uipanel(app.CALPanel);
            app.OperationPanel.Title = 'Operation';
            app.ClearButton = uibutton(app.OperationPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathClearButton, true);
            app.ClearButton.Tooltip = {'Clear calculator memory'};
            app.ClearButton.Text = 'C';
            app.CEButton = uibutton(app.OperationPanel, 'push');
            app.CEButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathCE, true);
            app.CEButton.Tooltip = {'Clear last command'};
            app.CEButton.Text = 'CE';
            app.ExecuteButton = uibutton(app.OperationPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathEnter, true);
            app.ExecuteButton.Tooltip = {'Execute command'};
            app.ExecuteButton.Text = '=';
            app.GRPButton = uibutton(app.OperationPanel, 'state');
            app.GRPButton.Tooltip = {'Toggle grouped operations'};
            app.GRPButton.Text = 'GRP';
            app.CommandField = uieditfield(app.OperationPanel, 'text');
            app.CommandField.Editable = 'off';
            app.CommandField.Tooltip = {'Calculator display'};
            app.MathLamp = uilamp(app.OperationPanel);
            app.MathLamp.Tooltip = {'Calculator status (gray - idle, yellow - operation incomplete, green - operation complete)'};
            app.FunctionsPanel = uipanel(app.CALPanel);
            app.FunctionsPanel.Title = 'Functions';
            app.RangeSpinnerLabel = uilabel(app.FunctionsPanel);
            app.RangeSpinnerLabel.Text = 'Range';
            app.MathRange1 = uispinner(app.FunctionsPanel);
            app.MathRange1.Tooltip = {'Range "from" or scalar attribute'};
            app.areaButton = uibutton(app.FunctionsPanel, 'push');
            app.areaButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathInt, true);
            app.areaButton.Tooltip = {'Integrate (opt. specify X range)'};
            app.areaButton.Text = 'area';
            app.minButton = uibutton(app.FunctionsPanel, 'push');
            app.minButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathMin, true);
            app.minButton.Tooltip = {'Minimum (opt. specify X range)'};
            app.minButton.Text = 'min';
            app.maxButton = uibutton(app.FunctionsPanel, 'push');
            app.maxButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathMax, true);
            app.maxButton.Tooltip = {'Maximum (opt. specify X range)'};
            app.maxButton.Text = 'max';
            app.XlimButton = uibutton(app.FunctionsPanel, 'push');
            app.XlimButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathYlim, true);
            app.XlimButton.Tooltip = {'Truncate to X range (from Range1 to Range2)'};
            app.XlimButton.Text = 'Xlim';
            app.YXButton = uibutton(app.FunctionsPanel, 'push');
            app.YXButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathYx, true);
            app.YXButton.Tooltip = {'Y value of spectrum at position X (type X value in Range1)'};
            app.YXButton.Text = 'Y(X)';
            app.mergeButton = uibutton(app.FunctionsPanel, 'push');
            app.mergeButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathMerge, true);
            app.mergeButton.Tooltip = {'Merge spectra'};
            app.mergeButton.Text = 'merge';
            app.normButton = uibutton(app.FunctionsPanel, 'push');
            app.normButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathNorm, true);
            app.normButton.Tooltip = {'Normalize to maximum (opt. specify X range)'};
            app.normButton.Text = 'norm';
            app.absButton = uibutton(app.FunctionsPanel, 'push');
            app.absButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathAbs, true);
            app.absButton.Tooltip = {'Absolute value'};
            app.absButton.Text = 'abs';
            app.PlusButton = uibutton(app.FunctionsPanel, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathPlus, true);
            app.PlusButton.Tooltip = {'Add'};
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.FunctionsPanel, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathMinus, true);
            app.MinusButton.Tooltip = {'Subtract'};
            app.MinusButton.Text = '-';
            app.MTimesButton = uibutton(app.FunctionsPanel, 'push');
            app.MTimesButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathMultiply, true);
            app.MTimesButton.Tooltip = {'Multiply'};
            app.MTimesButton.Text = '*';
            app.MDivideButton = uibutton(app.FunctionsPanel, 'push');
            app.MDivideButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathDivide, true);
            app.MDivideButton.Tooltip = {'Divide'};
            app.MDivideButton.Text = '/';
            app.meanButton = uibutton(app.FunctionsPanel, 'push');
            app.meanButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathMean, true);
            app.meanButton.Tooltip = {'Mean'};
            app.meanButton.Text = 'mean';
            app.sumButton = uibutton(app.FunctionsPanel, 'push');
            app.sumButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathSum, true);
            app.sumButton.Tooltip = {'Sum'};
            app.sumButton.Text = 'sum';
            app.lnButton = uibutton(app.FunctionsPanel, 'push');
            app.lnButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathLog, true);
            app.lnButton.Tooltip = {'Natural logarithm'};
            app.lnButton.Text = 'ln';
            app.NegateButton = uibutton(app.FunctionsPanel, 'push');
            app.NegateButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathNegate, true);
            app.NegateButton.Tooltip = {'Inverse sign'};
            app.NegateButton.Text = '+/-';
            app.diffButton = uibutton(app.FunctionsPanel, 'push');
            app.diffButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathDiff, true);
            app.diffButton.Tooltip = {'Differential'};
            app.diffButton.Text = 'diff';
            app.Button = uibutton(app.FunctionsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @cbMathPower, true);
            app.Button.Tooltip = {'Power'};
            app.Button.Text = '^';
            app.log10Button = uibutton(app.FunctionsPanel, 'push');
            app.log10Button.ButtonPushedFcn = createCallbackFcn(app, @cbMathLog10, true);
            app.log10Button.Tooltip = {'Base-10 logarithm'};
            app.log10Button.Text = 'log10';
            app.smoothButton = uibutton(app.FunctionsPanel, 'push');
            app.smoothButton.ButtonPushedFcn = createCallbackFcn(app, @cbMathSmooth, true);
            app.smoothButton.Tooltip = {'Smooth spectra (opt. specify number of points in Range1)'};
            app.smoothButton.Text = 'smooth';
            app.Label = uilabel(app.FunctionsPanel);
            app.Label.Text = '-';
            app.MathRange2 = uispinner(app.FunctionsPanel);
            app.MathRange2.Tooltip = {'Range "to"'};
            app.CALMemoryLabel = uilabel(app.CALPanel);
            app.CALMemoryLabel.Enable = 'off';
            app.CALMemoryLabel.Text = 'CAL Memory';
            app.CALInfoBox = uieditfield(app.CALPanel, 'text');
            app.CALInfoBox.Editable = 'off';
            app.CALInfoBox.Enable = 'off';
            app.SELEditFieldLabel = uilabel(app.DataTab);
            app.SELEditFieldLabel.Enable = 'off';
            app.SELEditFieldLabel.Text = 'SEL';
            app.SELInfoBox = uieditfield(app.DataTab, 'text');
            app.SELInfoBox.Editable = 'off';
            app.SELInfoBox.Enable = 'off';
            app.SELInfoBox.Value = '0 selected from 0 in group';
            % Show the figure after all components are created
            app.SpectrOMaticUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spectromatic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrOMaticUIFigure)

            % Execute the startup function
            runStartupFcn(app, @cbStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrOMaticUIFigure)
        end
    end
end